classdef proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TfEditField           matlab.ui.control.NumericEditField
        TfEditFieldLabel      matlab.ui.control.Label
        Button                matlab.ui.control.Button
        NodostEditField       matlab.ui.control.NumericEditField
        NodostEditFieldLabel  matlab.ui.control.Label
        NodosxEditField       matlab.ui.control.NumericEditField
        NodosxEditFieldLabel  matlab.ui.control.Label
        LEditField            matlab.ui.control.NumericEditField
        LEditFieldLabel       matlab.ui.control.Label
        T0tEditField          matlab.ui.control.NumericEditField
        T0tLabel              matlab.ui.control.Label
        Tx0EditField          matlab.ui.control.NumericEditField
        Tx0EditFieldLabel     matlab.ui.control.Label
        AlfaEditField         matlab.ui.control.NumericEditField
        AlfaEditFieldLabel    matlab.ui.control.Label
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % d^2T/dx^2 - a^2T = 0
            alfa = app.AlfaEditField.Value;
            L = app.LEditField.Value;
            
            dTxL = 0; % Se asume 0 dado que la temperatura no se puede seguir propagando
            Tx0 = app.Tx0EditField.Value;
            
            nx = app.NodosxEditField.Value;
            dx = L/(nx-1);
            
            a = zeros(nx-1,nx+1);
            a(1,2) = -2 - alfa^2*dx;
            a(1,3) = 1;
            for i = 2:nx-1
                a(i,i) = 1;
                a(i,i+1) = -2 - alfa^2*dx;
                a(i,i+2) = 1;
            end
            a(nx-1, end) = 0;
            a(nx-1, nx-1) = 2;
            a = a(:,2:nx);
            
            b = zeros(nx-1,1);
            b(1) = -Tx0;
            
            T = pivoteo(a,b,'parcial',0,false);
            T = [Tx0; T];
            plot(app.UIAxes,0:dx:L, T)


            % d^2T/dx^2 - a^2T = dT/dt
            alfa = app.AlfaEditField.Value;
            L = app.LEditField.Value;
            tf = app.TfEditField.Value;
            
            Tt0 = app.Tx0EditField.Value;
            dTxL = 0; % Se asume 0 dado que la temperatura no se puede seguir propagando
            Tx0 = app.T0tEditField.Value;
            
            nt = app.NodostEditField.Value;
            nx = app.NodosxEditField.Value;
            
            dx = L / (nx-1);
            dt = tf / (nt-1);
            
            for i = 1:nt
                if i == 1
                    a = diag(ones(1,nx-1));
                else
                    a = zeros(nx-1,nx+1);
                    a(1,2) = -2/dx^2 - alfa^2-1/dt;
                    a(1,3) = 1/dx^2;
                    for j = 2:nx-1
                        a(j,j) = 1/dx^2;
                        a(j,j+1) = -2/dx^2 - alfa^2-1/dt;
                        a(j,j+2) = 1/dx^2;
                    end
                    a(nx-1, nx-1) = 2*a(nx-1, nx-1);
                    a(nx-1, end) = 0;
                    a = a(:,2:nx);
                end
                
                if i == 1
                    b = ones(nx-1,1)*Tt0;
                else
                    b = - T(2:end)/dt;
                    b(1) = -Tx0/dx^2-Tt0/dt;
                end
            
                T = pivoteo(a,b,'parcial',0,false);
                T = [Tx0; T];

                plot(app.UIAxes_2, 0:dx:L, T,'DisplayName',['T = ',num2str((i-1)*dt)])
                hold(app.UIAxes_2, 'on')
            end
            hold(app.UIAxes_2, 'off')
            legend(app.UIAxes_2)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Estacionario')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Transitorio')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.AlfaEditFieldLabel = uilabel(app.UIFigure);
            app.AlfaEditFieldLabel.Text = 'Alfa';
            app.AlfaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlfaEditField.Value = 0.01;
            app.Tx0EditFieldLabel = uilabel(app.UIFigure);
            app.Tx0EditFieldLabel.Text = 'T(x,0)';
            app.Tx0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Tx0EditField.Value = 25;
            app.T0tLabel = uilabel(app.UIFigure);
            app.T0tLabel.Text = 'T(0,t)';
            app.T0tEditField = uieditfield(app.UIFigure, 'numeric');
            app.T0tEditField.Value = 50;
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEditField.Value = 10;
            app.NodosxEditFieldLabel = uilabel(app.UIFigure);
            app.NodosxEditFieldLabel.Text = 'Nodos x';
            app.NodosxEditField = uieditfield(app.UIFigure, 'numeric');
            app.NodosxEditField.Value = 20;
            app.NodostEditFieldLabel = uilabel(app.UIFigure);
            app.NodostEditFieldLabel.Text = 'Nodos t';
            app.NodostEditField = uieditfield(app.UIFigure, 'numeric');
            app.NodostEditField.Value = 5;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.TfEditFieldLabel = uilabel(app.UIFigure);
            app.TfEditFieldLabel.Text = 'Tf';
            app.TfEditField = uieditfield(app.UIFigure, 'numeric');
            app.TfEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end